classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        background               matlab.ui.control.Image
        clear_button             matlab.ui.control.Button
        redo_button              matlab.ui.control.Button
        undo_button              matlab.ui.control.Button
        save_button              matlab.ui.control.Button
        apply_button             matlab.ui.control.Button
        filter_lb                matlab.ui.control.ListBox
        browse_button            matlab.ui.control.Button
        FiltersListListBoxLabel  matlab.ui.control.Label
        BrightnessSliderLabel    matlab.ui.control.Label
        brightness_s             matlab.ui.control.Slider
        histogram                matlab.ui.control.UIAxes
        modified_image           matlab.ui.control.UIAxes
        original_image           matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function show_histo(app, img)
            [counts, bins] = imhist(img);
            bar(app.histogram, bins, counts, 'BarWidth', 1);
            xlabel(app.histogram, 'Pixel Intensity');
            ylabel(app.histogram, 'Frequency');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browse_button
        function browse_buttonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png';'*.bmp'}, 'Select an image file');
            
            if ~(isequal(filename, 0) || isequal(filepath, 0))
                selected_image = imread(fullfile(filepath, filename));
                
                imshow(selected_image, 'Parent', app.original_image);
                setappdata(0, 'original_img', selected_image);
                setappdata(0, 'modified_img', selected_image);
                show_histo(app, selected_image);
                imshow(selected_image,'Parent', app.modified_image);
                
                stack_undo = cell(1, 0);
                stack_undo{end + 1} = selected_image;
                setappdata(0, 'stack_undo', stack_undo);
                
                stack_redo = cell(1, 0);
                setappdata(0, 'stack_redo', stack_redo);
            end

        end

        % Button pushed function: apply_button
        function apply_buttonButtonPushed(app, event)
            selected_index = app.filter_lb.Value;
            stack_undo = getappdata(0, 'stack_undo');
            
            img = stack_undo{end};
            
            if selected_index == '1'
                modified_img = rgb_to_gray_avg(img);
            end
            
            stack_undo{end + 1} = modified_img;
            
            setappdata(0, 'stack_undo', stack_undo);
            
            stack_redo = cell(1, 0);
            setappdata(0, 'stack_redo', stack_redo);
            
            imshow(modified_img, 'Parent', app.modified_image);
        end

        % Button pushed function: undo_button
        function undo_buttonButtonPushed(app, event)
            stack_undo = getappdata(0, 'stack_undo');
            stack_redo = getappdata(0, 'stack_redo');
            if length(stack_undo) > 1
                i = stack_undo{end};
                
                stack_redo{end + 1} = i;
                
                stack_undo(end) = [];
                
                setappdata(0, 'stack_undo', stack_undo);
                setappdata(0, 'stack_redo', stack_redo);
                
                imshow(stack_undo{end}, 'Parent', app.modified_image);
            end
        end

        % Button pushed function: clear_button
        function clear_buttonButtonPushed(app, event)
            original = getappdata(0, 'original_img');
            
            imshow(original, 'Parent', app.modified_image);
            
            stack_undo = cell(1, 0);
            stack_undo{end + 1} = original;
            
            setappdata(0, 'stack_undo', stack_undo);
            
            stack_redo = cell(1, 0);
            setappdata(0, 'stack_redo', stack_redo);
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
            img = getappdata(0, 'stack_undo');
            imwrite(img{end}, "img.jpg", 'Quality', 100);
        end

        % Button pushed function: redo_button
        function redo_buttonButtonPushed(app, event)
            stack_undo = getappdata(0, 'stack_undo');
            stack_redo = getappdata(0, 'stack_redo');
            if ~(isempty(stack_redo))
                i = stack_redo{end};
                
                stack_undo{end + 1} = i;
                stack_redo(end) = [];
                
                setappdata(0, 'stack_undo', stack_undo);
                setappdata(0, 'stack_redo', stack_redo);
                
                imshow(i, 'Parent', app.modified_image);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_image = uiaxes(app.UIFigure);
            title(app.original_image, 'Original Image')
            app.original_image.XTick = [];
            app.original_image.YTick = [];
            app.modified_image = uiaxes(app.UIFigure);
            title(app.modified_image, 'Modified Image')
            app.modified_image.XTick = [];
            app.modified_image.YTick = [];
            app.histogram = uiaxes(app.UIFigure);
            title(app.histogram, 'Histogram')
            app.histogram.XTick = [];
            app.histogram.YTick = [];
            app.brightness_s = uislider(app.UIFigure);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.FiltersListListBoxLabel = uilabel(app.UIFigure);
            app.FiltersListListBoxLabel.Text = 'Filters List';
            app.browse_button = uibutton(app.UIFigure, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_buttonPushed, true);
            app.browse_button.Text = 'Browse Image';
            app.filter_lb = uilistbox(app.UIFigure);
            app.filter_lb.Items = {'RGB to Gray', 'Gray to Binary', 'RGB to Binary'};
            app.filter_lb.ItemsData = {'1', '2', '3'};
            app.filter_lb.Value = '1';
            app.apply_button = uibutton(app.UIFigure, 'push');
            app.apply_button.ButtonPushedFcn = createCallbackFcn(app, @apply_buttonButtonPushed, true);
            app.apply_button.Text = 'Apply Filter';
            app.save_button = uibutton(app.UIFigure, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Text = 'Save Image';
            app.undo_button = uibutton(app.UIFigure, 'push');
            app.undo_button.ButtonPushedFcn = createCallbackFcn(app, @undo_buttonButtonPushed, true);
            app.undo_button.Text = '↩';
            app.redo_button = uibutton(app.UIFigure, 'push');
            app.redo_button.ButtonPushedFcn = createCallbackFcn(app, @redo_buttonButtonPushed, true);
            app.redo_button.Text = '↪';
            app.clear_button = uibutton(app.UIFigure, 'push');
            app.clear_button.ButtonPushedFcn = createCallbackFcn(app, @clear_buttonButtonPushed, true);
            app.clear_button.Text = 'Clear Filters';
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = 'Browse_window.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end